classdef BZBS_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BZBSPlotterUIFigure          matlab.ui.Figure
        PlotBrillouinZonePanel       matlab.ui.container.Panel
        DeleteLinesButton_2          matlab.ui.control.Button
        PlotBZonCurrentAxesButton    matlab.ui.control.Button
        HorizontalButtonGroup        matlab.ui.container.ButtonGroup
        Button_2                     matlab.ui.control.RadioButton
        cButton_2                    matlab.ui.control.RadioButton
        bButton_2                    matlab.ui.control.RadioButton
        aButton_2                    matlab.ui.control.RadioButton
        VerticalButtonGroup          matlab.ui.container.ButtonGroup
        Button                       matlab.ui.control.RadioButton
        cButton                      matlab.ui.control.RadioButton
        bButton                      matlab.ui.control.RadioButton
        aButton                      matlab.ui.control.RadioButton
        InformationPanel             matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        PlotBrillouinZoneButton      matlab.ui.control.Button
        PlotUnitCellButton           matlab.ui.control.Button
        PlotBandStructurePanel       matlab.ui.container.Panel
        DeleteLinesButton            matlab.ui.control.Button
        PlotBandonCurrentAxesButton  matlab.ui.control.Button
        PlotBandButton               matlab.ui.control.Button
        TypeButtonGroup              matlab.ui.container.ButtonGroup
        NOSOCButton                  matlab.ui.control.RadioButton
        SOCButton                    matlab.ui.control.RadioButton
        KPoint2EditField             matlab.ui.control.EditField
        KPoint2EditFieldLabel        matlab.ui.control.Label
        KPoint1EditField             matlab.ui.control.EditField
        KPoint1Label                 matlab.ui.control.Label
        FetchProfilefromTQCPanel     matlab.ui.container.Panel
        GetButton                    matlab.ui.control.Button
        ICSDEditField                matlab.ui.control.NumericEditField
        ICSDEditFieldLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        BZBS % Description
    end
    
    methods (Access = private)
        
        function results = getInfo(app)

            name = append("Name: ", app.BZBS.TQC_profile.chemicalFormulaStructural,newline);

            kpoints = append("Kpoints: ", strjoin(app.BZBS.kPathGraph.Nodes.Name) ,newline);

            BZsize = append("BZ size: ", num2str(app.BZBS.kPath.b1(1)),', ',num2str(app.BZBS.kPath.b2(2)),', ',num2str(app.BZBS.kPath.b3(3)),newline);
            
            results = append(name,kpoints,BZsize);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            app.GetButton.Enable = 0;
            app.BZBS = bz_GRAPH(app.ICSDEditField.Value);
            pause(0.1);
            app.GetButton.Enable = 1;
            app.TextArea.Value = app.getInfo();
        end

        % Button pushed function: PlotUnitCellButton
        function PlotUnitCellButtonPushed(app, event)
            app.BZBS.plotUnitCell();
        end

        % Button pushed function: PlotBrillouinZoneButton
        function PlotBrillouinZoneButtonPushed(app, event)
            app.BZBS.plotBrillouinZone();
        end

        % Button pushed function: PlotBandonCurrentAxesButton
        function PlotBandonCurrentAxesButtonPushed(app, event)
            hns = app.KPoint1EditField.Value;
            hnt = app.KPoint2EditField.Value;
            if app.SOCButton.Value == 1
                soc = 'soc';
            else
                soc = 'nosoc';
            end
            app.BZBS.plotBandOnCurrentAxes(hns,hnt,soc);
        end

        % Button pushed function: PlotBandButton
        function PlotBandButtonPushed(app, event)
            hns = app.KPoint1EditField.Value;
            hnt = app.KPoint2EditField.Value;
            if app.SOCButton.Value == 1
                soc = 'soc';
            else
                soc = 'nosoc';
            end
            app.BZBS.plotBand(hns,hnt,soc);
        end

        % Button pushed function: DeleteLinesButton, DeleteLinesButton_2
        function DeleteLinesButtonPushed(app, event)
            h = findobj(gca,'Type','line');
            delete(h(:));
            h = findobj(gca,'Type','ConstantLine');
            delete(h(:));
        end

        % Button pushed function: PlotBZonCurrentAxesButton
        function PlotBZonCurrentAxesButtonPushed(app, event)
            vl = app.VerticalButtonGroup.SelectedObject.Text;
            hl = app.HorizontalButtonGroup.SelectedObject.Text;
            app.BZBS.plotBZonCurrentAxes(vl,hl)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BZBSPlotterUIFigure = uifigure('Visible', 'off');
            app.BZBSPlotterUIFigure.Name = 'BZBS Plotter';
            app.FetchProfilefromTQCPanel = uipanel(app.BZBSPlotterUIFigure);
            app.FetchProfilefromTQCPanel.Title = 'Fetch Profile from TQC';
            app.ICSDEditFieldLabel = uilabel(app.FetchProfilefromTQCPanel);
            app.ICSDEditFieldLabel.Text = 'ICSD';
            app.ICSDEditField = uieditfield(app.FetchProfilefromTQCPanel, 'numeric');
            app.ICSDEditField.ValueDisplayFormat = '%.0f';
            app.GetButton = uibutton(app.FetchProfilefromTQCPanel, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.PlotBandStructurePanel = uipanel(app.BZBSPlotterUIFigure);
            app.PlotBandStructurePanel.Title = 'Plot Band Structure';
            app.KPoint1Label = uilabel(app.PlotBandStructurePanel);
            app.KPoint1Label.Text = 'KPoint 1';
            app.KPoint1EditField = uieditfield(app.PlotBandStructurePanel, 'text');
            app.KPoint2EditFieldLabel = uilabel(app.PlotBandStructurePanel);
            app.KPoint2EditFieldLabel.Text = 'KPoint 2';
            app.KPoint2EditField = uieditfield(app.PlotBandStructurePanel, 'text');
            app.TypeButtonGroup = uibuttongroup(app.PlotBandStructurePanel);
            app.TypeButtonGroup.Title = 'Type';
            app.SOCButton = uiradiobutton(app.TypeButtonGroup);
            app.SOCButton.Text = 'SOC';
            app.SOCButton.Value = true;
            app.NOSOCButton = uiradiobutton(app.TypeButtonGroup);
            app.NOSOCButton.Text = 'NO-SOC';
            app.PlotBandButton = uibutton(app.PlotBandStructurePanel, 'push');
            app.PlotBandButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBandButtonPushed, true);
            app.PlotBandButton.Text = 'Plot Band';
            app.PlotBandonCurrentAxesButton = uibutton(app.PlotBandStructurePanel, 'push');
            app.PlotBandonCurrentAxesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBandonCurrentAxesButtonPushed, true);
            app.PlotBandonCurrentAxesButton.Text = 'Plot Band on Current Axes';
            app.DeleteLinesButton = uibutton(app.PlotBandStructurePanel, 'push');
            app.DeleteLinesButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLinesButtonPushed, true);
            app.DeleteLinesButton.Text = 'Delete Lines';
            app.InformationPanel = uipanel(app.BZBSPlotterUIFigure);
            app.InformationPanel.Title = 'Information';
            app.PlotUnitCellButton = uibutton(app.InformationPanel, 'push');
            app.PlotUnitCellButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUnitCellButtonPushed, true);
            app.PlotUnitCellButton.Text = 'Plot Unit Cell';
            app.PlotBrillouinZoneButton = uibutton(app.InformationPanel, 'push');
            app.PlotBrillouinZoneButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBrillouinZoneButtonPushed, true);
            app.PlotBrillouinZoneButton.Text = 'Plot Brillouin Zone';
            app.TextArea = uitextarea(app.InformationPanel);
            app.PlotBrillouinZonePanel = uipanel(app.BZBSPlotterUIFigure);
            app.PlotBrillouinZonePanel.Title = 'Plot Brillouin Zone';
            app.VerticalButtonGroup = uibuttongroup(app.PlotBrillouinZonePanel);
            app.VerticalButtonGroup.Title = 'Vertical';
            app.aButton = uiradiobutton(app.VerticalButtonGroup);
            app.aButton.Text = 'a';
            app.aButton.Value = true;
            app.bButton = uiradiobutton(app.VerticalButtonGroup);
            app.bButton.Text = 'b';
            app.cButton = uiradiobutton(app.VerticalButtonGroup);
            app.cButton.Text = 'c';
            app.Button = uiradiobutton(app.VerticalButtonGroup);
            app.Button.Text = '\';
            app.HorizontalButtonGroup = uibuttongroup(app.PlotBrillouinZonePanel);
            app.HorizontalButtonGroup.Title = 'Horizontal';
            app.aButton_2 = uiradiobutton(app.HorizontalButtonGroup);
            app.aButton_2.Text = 'a';
            app.aButton_2.Value = true;
            app.bButton_2 = uiradiobutton(app.HorizontalButtonGroup);
            app.bButton_2.Text = 'b';
            app.cButton_2 = uiradiobutton(app.HorizontalButtonGroup);
            app.cButton_2.Text = 'c';
            app.Button_2 = uiradiobutton(app.HorizontalButtonGroup);
            app.Button_2.Text = '\';
            app.PlotBZonCurrentAxesButton = uibutton(app.PlotBrillouinZonePanel, 'push');
            app.PlotBZonCurrentAxesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotBZonCurrentAxesButtonPushed, true);
            app.PlotBZonCurrentAxesButton.Text = 'Plot BZ on Current Axes';
            app.DeleteLinesButton_2 = uibutton(app.PlotBrillouinZonePanel, 'push');
            app.DeleteLinesButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteLinesButtonPushed, true);
            app.DeleteLinesButton_2.Text = 'Delete Lines';
            % Show the figure after all components are created
            app.BZBSPlotterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BZBS_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BZBSPlotterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BZBSPlotterUIFigure)
        end
    end
end